classdef DPABISurf_MultiEcho_UI < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        AcceptButton                matlab.ui.control.Button
        MultiEchoDataAnalysisMethodButtonGroup  matlab.ui.container.ButtonGroup
        TedanaMEICAdenoisingButton  matlab.ui.control.RadioButton
        OptimizedcombinationButton  matlab.ui.control.RadioButton
    end

    properties (Access = public)
        Cfg struct
        IsAccepted logical = false;
    end
    
    
    methods (Access = public)


        function initializeFromCfg(comp)

            Cfg = comp.Cfg;

            if isempty(Cfg)
                Cfg = struct;
            end

            if ~isfield(Cfg, 'MultiEcho') || ~isstruct(Cfg.MultiEcho)
                Cfg.MultiEcho = struct;
            end

            if ~isfield(Cfg.MultiEcho, 'MultiEchoMethod') || isempty(Cfg.MultiEcho.MultiEchoMethod)
                Cfg.MultiEcho.MultiEchoMethod = 'OptimizedCombination';
            end

            comp.Cfg = Cfg;
            comp.IsAccepted = false;

            CurrentMethod = strtrim(comp.Cfg.MultiEcho.MultiEchoMethod);

            %disp(['CurrentMethod = ', CurrentMethod])

            if strcmpi(CurrentMethod, 'MultiEchoICA_Tedana')

                comp.MultiEchoDataAnalysisMethodButtonGroup.SelectedObject = comp.TedanaMEICAdenoisingButton;

            else

                comp.MultiEchoDataAnalysisMethodButtonGroup.SelectedObject = comp.OptimizedcombinationButton;

            end

            %disp(['SelectedObject = ', comp.MultiEchoDataAnalysisMethodButtonGroup.SelectedObject.Text])

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(comp, event)
            

            selectedButton = comp.MultiEchoDataAnalysisMethodButtonGroup.SelectedObject;

            if selectedButton == comp.OptimizedcombinationButton

                comp.Cfg.MultiEcho.MultiEchoMethod = 'OptimizedCombination';

            elseif selectedButton == comp.TedanaMEICAdenoisingButton

                comp.Cfg.MultiEcho.MultiEchoMethod = 'MultiEchoICA_Tedana';

            else

                comp.Cfg.MultiEcho.MultiEchoMethod = 'OptimizedCombination';

            end

            comp.IsAccepted = true;

            fig = ancestor(comp, 'figure');

            if ~isempty(fig) && isvalid(fig)
                uiresume(fig);
            end


        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.MultiEchoDataAnalysisMethodButtonGroup = uibuttongroup(comp);
            comp.MultiEchoDataAnalysisMethodButtonGroup.Title = 'Multi Echo Data Analysis Method';
            comp.OptimizedcombinationButton = uiradiobutton(comp.MultiEchoDataAnalysisMethodButtonGroup);
            comp.OptimizedcombinationButton.Text = 'Optimized combination';
            comp.OptimizedcombinationButton.Value = true;
            comp.TedanaMEICAdenoisingButton = uiradiobutton(comp.MultiEchoDataAnalysisMethodButtonGroup);
            comp.TedanaMEICAdenoisingButton.Text = 'Tedana / ME-ICA denoising';
            comp.AcceptButton = uibutton(comp, 'push');
            comp.AcceptButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AcceptButtonPushed, true);
            comp.AcceptButton.Text = 'Accept';
        end
    end
end