classdef SurfaceSpace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LeftHemisphereLabel    matlab.ui.control.Label
        RightHemisphereLabel   matlab.ui.control.Label
        ListBox                matlab.ui.control.ListBox
        ListBox_2              matlab.ui.control.ListBox
        AddimageButton         matlab.ui.control.Button
        RemoveButton           matlab.ui.control.Button
        MaskButton             matlab.ui.control.Button
        RemoveAllButton        matlab.ui.control.Button
        AddimageButton_2       matlab.ui.control.Button
        RemoveButton_2         matlab.ui.control.Button
        RemoveAllButton_2      matlab.ui.control.Button
        MaskButton_2           matlab.ui.control.Button
        ListBox_3              matlab.ui.control.ListBox
        ListBox_4              matlab.ui.control.ListBox
        BacktoMainPanelButton  matlab.ui.control.Button
    end

    
   properties (Access = public)
       MainApp
   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddimageButton
        function AddimageButtonPushed(app, event)
            % =========================
            % 1. 选择 GIFTI 文件
            % =========================
            [fileName, pathName] = uigetfile( ...
                {'*.gii','GIFTI Files (*.gii)'; '*.*','All Files (*.*)'}, ...
                'Select Left Hemisphere GIFTI File(s)', ...
                'MultiSelect','on');
        
            % 用户取消
            if isequal(fileName,0)
                return;
            end
        
            % =========================
            % 2. 转成 cell
            % =========================
            if ~iscell(fileName)
                fileName = {fileName};
            end
        
            % 完整路径
            fileList = fullfile(pathName, fileName);
        
            % 转列 cell
            fileList = fileList(:);
        
            % =========================
            % 3. 初始化 UserData
            % =========================
            if isempty(app.UIFigure.UserData)
                app.UIFigure.UserData = struct;
            end
        
            % =========================
            % 4. 初始化 LH_GII
            % =========================
            if ~isfield(app.UIFigure.UserData, 'LH_GII')
                app.UIFigure.UserData.LH_GII = {};
            end
        
            % =========================
            % 5. 保存
            % =========================
            app.UIFigure.UserData.LH_GII = ...
                [app.UIFigure.UserData.LH_GII; fileList];
        
            % =========================
            % 6. 更新 ListBox
            % =========================
            app.ListBox.Items = app.UIFigure.UserData.LH_GII;

        end

        % Button pushed function: AddimageButton_2
        function AddimageButton_2Pushed(app, event)
           % =========================
            % 1. 选择 GIFTI 文件
            % =========================
            [fileName, pathName] = uigetfile( ...
                {'*.gii','GIFTI Files (*.gii)'; '*.*','All Files (*.*)'}, ...
                'Select Right Hemisphere GIFTI File(s)', ...
                'MultiSelect','on');
        
            % 用户取消
            if isequal(fileName,0)
                return;
            end
        
            % =========================
            % 2. 转成 cell
            % =========================
            if ~iscell(fileName)
                fileName = {fileName};
            end
        
            % 完整路径
            fileList = fullfile(pathName, fileName);
        
            % 转列 cell
            fileList = fileList(:);
        
            % =========================
            % 3. 初始化 UserData
            % =========================
            if isempty(app.UIFigure.UserData)
                app.UIFigure.UserData = struct;
            end
        
            % =========================
            % 4. 初始化 RH_GII
            % =========================
            if ~isfield(app.UIFigure.UserData, 'RH_GII')
                app.UIFigure.UserData.RH_GII = {};
            end
        
            % =========================
            % 5. 保存
            % =========================
            app.UIFigure.UserData.RH_GII = ...
                [app.UIFigure.UserData.RH_GII; fileList];
        
            % =========================
            % 6. 更新 ListBox
            % =========================
            app.ListBox_2.Items = app.UIFigure.UserData.RH_GII;

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % 没有数据
            if ~isfield(app.UIFigure.UserData, 'LH_GII')
                return;
            end
        
            % 当前选中项
            selected = app.ListBox.Value;
        
            if isempty(selected)
                return;
            end
        
            % 当前全部项
            items = app.UIFigure.UserData.LH_GII;
        
            % 找到索引
            idx = ismember(items, selected);
        
            % 删除
            app.UIFigure.UserData.LH_GII(idx) = [];
        
            % 更新 ListBox
            app.ListBox.Items = app.UIFigure.UserData.LH_GII;
        
            % 更新 Value
            if isempty(app.UIFigure.UserData.LH_GII)
                app.ListBox.Value = {};
            else
                app.ListBox.Value = app.UIFigure.UserData.LH_GII{1};
            end
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
            % 没有数据
            if ~isfield(app.UIFigure.UserData, 'RH_GII')
                return;
            end
        
            % 当前选中项
            selected = app.ListBox_2.Value;
        
            if isempty(selected)
                return;
            end
        
            % 当前全部项
            items = app.UIFigure.UserData.RH_GII;
        
            % 找到索引
            idx = ismember(items, selected);
        
            % 删除
            app.UIFigure.UserData.RH_GII(idx) = [];
        
            % 更新 ListBox
            app.ListBox_2.Items = app.UIFigure.UserData.RH_GII;
        
            % 更新 Value
            if isempty(app.UIFigure.UserData.RH_GII)
                app.ListBox_2.Value = {};
            else
                app.ListBox_2.Value = app.UIFigure.UserData.RH_GII{1};
            end
        end

        % Button pushed function: RemoveAllButton
        function RemoveAllButtonPushed(app, event)
             % 清空数据
            app.UIFigure.UserData.LH_GII = {};
        
            % 清空 ListBox
            app.ListBox.Items = {};
        
            % 清空选中值
            app.ListBox.Value = {};
        end

        % Button pushed function: RemoveAllButton_2
        function RemoveAllButton_2Pushed(app, event)
            % 清空数据
            app.UIFigure.UserData.RH_GII = {};
        
            % 清空 ListBox
            app.ListBox_2.Items = {};
        
            % 清空选中值
            app.ListBox_2.Value = {};
        end

        % Button pushed function: BacktoMainPanelButton
        function BacktoMainPanelButtonPushed(app, event)
        
        try
            % =========================
            % MainApp check
            % =========================
            if isempty(app.MainApp) || ~isvalid(app.MainApp)
                uialert(app.UIFigure,'Main app not found','Error');
                return;
            end
        
            % =========================
            % Load LH / RH
            % =========================
            UD = app.UIFigure.UserData;

            LH_GII = {};
            RH_GII = {};
            
            if isstruct(UD)
                if isfield(UD,'LH_GII')
                    LH_GII = UD.LH_GII;
                end
                if isfield(UD,'RH_GII')
                    RH_GII = UD.RH_GII;
                end
            end
            
            % 如果不匹配也不管，直接允许返回
            nSub = min(numel(LH_GII), numel(RH_GII));
            
            InputData = cell(nSub,1);
            for i = 1:nSub
                InputData{i} = {LH_GII{i}, RH_GII{i}};
            end
        
            % =========================
            % Build InputData (PAIRWISE)
            % =========================
            nSub = numel(LH_GII);
            InputData = cell(nSub,1);
            for i = 1:nSub
                InputData{i} = {LH_GII{i}, RH_GII{i}};
            end
        
            % =========================
            % Write back ONLY data to MainApp
            % =========================
            mainUD = app.MainApp.UIFigure.UserData;
        
            mainUD.LH_GII = LH_GII;
            mainUD.RH_GII = RH_GII;
            mainUD.InputData = InputData;
            mainUD.InputType = 'gii';
        
            % optional: for display only
            mainUD.InputFiles = [LH_GII(:); RH_GII(:)];
        
            app.MainApp.UIFigure.UserData = mainUD;
        
            % =========================
            % Close SurfaceSpace
            % =========================
            delete(app);
        
        catch ME
            uialert(app.UIFigure,ME.message,'Error');
        end

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            % =========================
            % 1. 选择 mask 文件（左半球）
            % =========================
            [fileName, pathName] = uigetfile( ...
                {'*.gii;*.nii;*.nii.gz', ...
                'Mask Files (*.gii, *.nii, *.nii.gz)'; ...
                '*.*','All Files (*.*)'}, ...
                'Select Left Hemisphere Mask');
        
            if isequal(fileName,0)
                return;
            end
        
            maskFile = fullfile(pathName, fileName);
        
            % =========================
            % 2. 初始化结构
            % =========================
            if isempty(app.UIFigure.UserData)
                app.UIFigure.UserData = struct;
            end
        
            if ~isfield(app.UIFigure.UserData, 'LH_Mask')
                app.UIFigure.UserData.LH_Mask = {};
            end
        
            % =========================
            % 3. 存储（支持多次添加）
            % =========================
            app.UIFigure.UserData.LH_Mask = ...
                [app.UIFigure.UserData.LH_Mask; {maskFile}];
        
            % =========================
            % 4. 更新 ListBox_3
            % =========================
            app.ListBox_3.Items = app.UIFigure.UserData.LH_Mask;
        
            % 自动选中最新一个
            app.ListBox_3.Value = app.UIFigure.UserData.LH_Mask{end};
        
        end

        % Button pushed function: MaskButton_2
        function MaskButton_2Pushed(app, event)
            % =========================
            % 1. 选择 mask 文件（右半球）
            % =========================
            [fileName, pathName] = uigetfile( ...
                {'*.gii;*.nii;*.nii.gz', ...
                'Mask Files (*.gii, *.nii, *.nii.gz)'; ...
                '*.*','All Files (*.*)'}, ...
                'Select Right Hemisphere Mask');
        
            if isequal(fileName,0)
                return;
            end
        
            maskFile = fullfile(pathName, fileName);
        
            % =========================
            % 2. 初始化结构
            % =========================
            if isempty(app.UIFigure.UserData)
                app.UIFigure.UserData = struct;
            end
        
            if ~isfield(app.UIFigure.UserData, 'RH_Mask')
                app.UIFigure.UserData.RH_Mask = {};
            end
        
            % =========================
            % 3. 存储
            % =========================
            app.UIFigure.UserData.RH_Mask = ...
                [app.UIFigure.UserData.RH_Mask; {maskFile}];
        
            % =========================
            % 4. 更新 ListBox_4
            % =========================
            app.ListBox_4.Items = app.UIFigure.UserData.RH_Mask;
        
            % 自动选中最新一个
            app.ListBox_4.Value = app.UIFigure.UserData.RH_Mask{end};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftHemisphereLabel = uilabel(app.UIFigure);
            app.LeftHemisphereLabel.Text = 'Left Hemisphere';
            app.RightHemisphereLabel = uilabel(app.UIFigure);
            app.RightHemisphereLabel.Text = 'Right Hemisphere';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.AddimageButton = uibutton(app.UIFigure, 'push');
            app.AddimageButton.ButtonPushedFcn = createCallbackFcn(app, @AddimageButtonPushed, true);
            app.AddimageButton.Text = 'Add image';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.MaskButton = uibutton(app.UIFigure, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.RemoveAllButton = uibutton(app.UIFigure, 'push');
            app.RemoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButtonPushed, true);
            app.RemoveAllButton.Text = 'Remove All';
            app.AddimageButton_2 = uibutton(app.UIFigure, 'push');
            app.AddimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddimageButton_2Pushed, true);
            app.AddimageButton_2.Text = 'Add image';
            app.RemoveButton_2 = uibutton(app.UIFigure, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.RemoveAllButton_2 = uibutton(app.UIFigure, 'push');
            app.RemoveAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButton_2Pushed, true);
            app.RemoveAllButton_2.Text = 'Remove All';
            app.MaskButton_2 = uibutton(app.UIFigure, 'push');
            app.MaskButton_2.ButtonPushedFcn = createCallbackFcn(app, @MaskButton_2Pushed, true);
            app.MaskButton_2.Text = 'Mask';
            app.ListBox_3 = uilistbox(app.UIFigure);
            app.ListBox_3.Items = {};
            app.ListBox_3.Value = {};
            app.ListBox_4 = uilistbox(app.UIFigure);
            app.ListBox_4.Items = {};
            app.ListBox_4.Value = {};
            app.BacktoMainPanelButton = uibutton(app.UIFigure, 'push');
            app.BacktoMainPanelButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainPanelButtonPushed, true);
            app.BacktoMainPanelButton.Text = 'Back to Main Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SurfaceSpace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end