classdef dpabi_Gradient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        AddimageButton                  matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        MaskButton                      matlab.ui.control.Button
        GradientCalculationLabel        matlab.ui.control.Label
        DownloadBrainspaceButton        matlab.ui.control.Button
        SetBrainSpacePathButton         matlab.ui.control.Button
        CalGradientButton               matlab.ui.control.Button
        ReferenceLabel_2                matlab.ui.control.Label
        nIterationsLabel_2              matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        KernelDropDownLabel             matlab.ui.control.Label
        KernelDropDown                  matlab.ui.control.DropDown
        ApproachDropDownLabel           matlab.ui.control.Label
        ApproachDropDown                matlab.ui.control.DropDown
        NumberofgradientsDropDownLabel  matlab.ui.control.Label
        NumberofgradientsDropDown       matlab.ui.control.DropDown
        NumberofGradientsEditFieldLabel  matlab.ui.control.Label
        NumberofGradientsEditField      matlab.ui.control.EditField
        nIterationsLabel                matlab.ui.control.Label
        nIterationsEditField            matlab.ui.control.NumericEditField
        GoSurfaceSpacegiiButton         matlab.ui.control.Button
        RemoveAllButton                 matlab.ui.control.Button
        ReferenceLabel                  matlab.ui.control.Label
        ReferenceDropDown               matlab.ui.control.DropDown
        GradientAlignmentDropDownLabel  matlab.ui.control.Label
        GradientAlignmentDropDown       matlab.ui.control.DropDown
        SparsitythresholdEditFieldLabel  matlab.ui.control.Label
        SparsitythresholdEditField      matlab.ui.control.NumericEditField
        SparsitythresholdDropDown       matlab.ui.control.DropDown
        SparsitythresholdDropDownLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GradientMetricsLabel            matlab.ui.control.Label
        ExplanationratioCheckBox        matlab.ui.control.CheckBox
        GradientrangeCheckBox           matlab.ui.control.CheckBox
        GradientvarianceCheckBox        matlab.ui.control.CheckBox
        GradientdispersionCheckBox      matlab.ui.control.CheckBox
        GradientVisualizationLabel      matlab.ui.control.Label
        GradientmapsCheckBox            matlab.ui.control.CheckBox
        ScatterplotCheckBox             matlab.ui.control.CheckBox
        ScreeplotCheckBox               matlab.ui.control.CheckBox
        OutputDirButton                 matlab.ui.control.Button
        OutputDirEditFieldLabel         matlab.ui.control.Label
        OutputDirEditField              matlab.ui.control.EditField
        CalGradientMetricsButton        matlab.ui.control.Button
        RemoveButton_2                  matlab.ui.control.Button
        AddGradientButton               matlab.ui.control.Button
        GradientMetricsCalculationLabel  matlab.ui.control.Label
        CalGradientVisualizationButton  matlab.ui.control.StateButton
        ListBox_2                       matlab.ui.control.ListBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 1. 默认 alignment = None
            %app.GradientAlignmentDropDown.Value = 'None';
        
            %% 2. 隐藏 / 关闭 alignment 参数
            %app.nIterationsEditField.Visible = 'off';
            %app.ReferenceDropDown.Visible = 'off';
        
            %% 3. （推荐）如果有panel，可以一起隐藏/关闭
            % app.GradientAlignmentPanel.Visible = 'off';
        
            %% 4. 设置默认安全值（防止后面调用报错）
            %app.nIterationsEditField.Value = 10;
            %app.ReferenceDropDown.Value = 'first gradient';
        
            %% 5. 状态提示（可选）
            % app.StatusLabel.Text = 'Ready';
        end

        % Button pushed function: AddimageButton
        function AddimageButtonPushed(app, event)

            % =====================================
            % Initialize UserData
            % =====================================
            if isempty(app.UIFigure.UserData)
        
                app.UIFigure.UserData = struct;
        
            end
        
            % =====================================
            % Choose Input Mode
            % =====================================
            choice = uiconfirm(app.UIFigure, ...
                sprintf(['Choose input type:\n\n' ...
                'Files: select one or more files\n' ...
                'Folder: import all supported files in a folder']), ...
                'Add Image', ...
                'Options', {'Files', 'Folder', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 3);
        
            if strcmp(choice, 'Cancel')
                return;
            end
        
            % =====================================
            % Select Files
            % =====================================
            switch choice
        
                case 'Files'
        
                    [files, path] = uigetfile( ...
                        {'*.mat;*.txt;*.csv;*.gii;*.nii;*.nii.gz', ...
                        'Supported Files'}, ...
                        'Select Input Files', ...
                        'MultiSelect', 'on');
        
                    if isequal(files,0)
                        return;
                    end
        
                    if ischar(files)
                        files = {files};
                    end
                    
                    FileList = cell(length(files),1);
   
                    for i = 1:length(files)
                    
                        FileList{i} = fullfile(path, files{i});
                    
                    end        
                
                case 'Folder'
        
                    folder = uigetdir(pwd, ...
                        'Select Input Folder');
        
                    if isequal(folder,0)
                        return;
                    end
        
                    exts = { ...
                        '*.mat', ...
                        '*.txt', ...
                        '*.csv', ...
                        '*.gii', ...
                        '*.nii', ...
                        '*.nii.gz'};
        
                    FileList = {};
        
                    for i = 1:length(exts)
        
                        tmp = dir(fullfile(folder, exts{i}));
        
                        for j = 1:length(tmp)
        
                            FileList{end+1} = ...
                                fullfile(folder, tmp(j).name);
        
                        end
                    end
            end
        
            % =====================================
            % No Files
            % =====================================
            if isempty(FileList)
        
                uialert(app.UIFigure, ...
                    'No supported files found.', ...
                    'Input Error');
        
                return;
        
            end
        
            % =====================================
            % First File
            % =====================================
            FirstFile = FileList{1};
        
            [FilePath, FileName, Ext] = ...
                fileparts(FirstFile);
        
            % nii.gz
            if strcmpi(Ext,'.gz')
        
                [~, FileName2, Ext2] = ...
                    fileparts(FileName);
        
                Ext = [Ext2 Ext];
        
            end
        
            % =====================================
            % Detect Input Type
            % =====================================
            if contains(FilePath, ...
                    'FCMetrics', ...
                    'IgnoreCase', true)
        
                InputType = 'fc';
        
            elseif strcmpi(Ext,'.gii')
        
                InputType = 'gii';
        
            elseif strcmpi(Ext,'.nii') || ...
                   strcmpi(Ext,'.nii.gz')
        
                InputType = 'nii';
        
            else
        
                % ---------------------------------
                % Matrix File
                % ---------------------------------
                switch lower(Ext)
        
                    case '.mat'
        
                        tmp = load(FirstFile);
        
                        fn = fieldnames(tmp);
        
                        Data = tmp.(fn{1});
        
                    case '.txt'
        
                        Data = readmatrix(FirstFile);
        
                    case '.csv'
        
                        Data = readmatrix(FirstFile);
        
                    otherwise
        
                        uialert(app.UIFigure, ...
                            'Unsupported file type.', ...
                            'Input Error');
        
                        return;
        
                end
        
                % ---------------------------------
                % Detect FC / Time Series
                % ---------------------------------
                if size(Data,1) == size(Data,2)
        
                    InputType = 'fc';
        
                else
        
                    InputType = 'timeseries';
        
                end
        
            end
        
            % =====================================
            % Load All InputData
            % =====================================
            InputData = cell(length(FileList),1);
        
            for iFile = 1:length(FileList)
        
                CurrentFile = FileList{iFile};
        
                [~, FileName, Ext] = ...
                    fileparts(CurrentFile);
        
                % nii.gz
                if strcmpi(Ext,'.gz')
        
                    [~, FileName2, Ext2] = ...
                        fileparts(FileName);
        
                    Ext = [Ext2 Ext];
        
                end
        
                switch lower(InputType)
        
                    % ---------------------------------
                    % FC / Time Series
                    % ---------------------------------
                    case {'fc','timeseries'}
        
                        switch lower(Ext)
        
                            case '.mat'
        
                                tmp = load(CurrentFile);
        
                                fn = fieldnames(tmp);
        
                                InputData{iFile} = ...
                                    tmp.(fn{1});
        
                            case '.txt'
        
                                InputData{iFile} = ...
                                    readmatrix(CurrentFile);
        
                            case '.csv'
        
                                InputData{iFile} = ...
                                    readmatrix(CurrentFile);
        
                        end
        
                    % ---------------------------------
                    % GIfTI
                    % ---------------------------------
                    case 'gii'
        
                        g = gifti(CurrentFile);
        
                        InputData{iFile} = ...
                            g.cdata;
        
                    % ---------------------------------
                    % NIfTI
                    % ---------------------------------
                    case 'nii'
        
                        InputData{iFile} = ...
                            niftiread(CurrentFile);
        
                end
        
            end
        
            % =====================================
            % Store
            % =====================================
            app.UIFigure.UserData.InputFiles = ...
                FileList;
        
            app.UIFigure.UserData.InputType = ...
                InputType;
        
            app.UIFigure.UserData.InputData = ...
                InputData;
        
            % =====================================
            % Display
            % =====================================
            app.ListBox.Items = FileList;
        
            fprintf('\n=================================\n');
            fprintf('Input Loaded\n');
            fprintf('=================================\n');
        
            fprintf('Detected Type: %s\n', ...
                InputType);
        
            fprintf('Number of Subjects: %d\n', ...
                length(InputData));
        
        end

        % Callback function
        function GradientCalculationDropDownValueChanged(app, event)
            value = app.GradientCalculationDropDown.Value;
            switch value
                
                case 'none'
                    app.SigmaEditField.Visible = 'off';
                
                case 'pearson'
                    app.SigmaEditField.Visible = 'off';

                case 'spearman'
                    app.SigmaEditField.Visible = 'off';

                case 'gaussian'
                    % 显示子选项
                    app.SigmaEditField.Visible = 'on';
            end
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % 先全部隐藏
            app.SparsityEditField.Visible = 'off';
            app.KernelDropDown.Visible = 'off';
            app.SigmaEditField.Visible = 'off';
            app.ApproachDropDown.Visible = 'off';
            app.GradientNumEditField.Visible = 'off';

            % 再根据选择显示
            switch value
        
                case 'Sparsity threshold'
                    app.SparsityEditField.Visible = 'on';
        
                case 'Kernel'
                    app.KernelDropDown.Visible = 'on';
        
                case 'Approach'
                    app.ApproachDropDown.Visible = 'on';
        
                case 'Number of gradients'
                    app.GradientNumEditField.Visible = 'on';
        
            end
        end

        % Callback function
        function DownloadBrainspaceButtonPushed(app, event)
             url = 'https://github.com/MICA-MNI/BrainSpace';

            % 打开浏览器
            web(url, '-browser');
        
            % 提示用户
            uialert(app.UIFigure, ...
                ['BrainSpace download page opened.' newline ...
                'Please download it, then click "Set BrainSpace Path".'], ...
                'Download');
        end

        % Button pushed function: DownloadBrainspaceButton
        function DownloadBrainspaceButtonPushed2(app, event)
            url = 'https://github.com/MICA-MNI/BrainSpace/tags';

            web(url, '-browser');
        
            uialert(app.UIFigure, ...
                ['BrainSpace download page opened.' newline ...
                 'Please download it, then click "Set BrainSpace Path".'], ...
                'Download');
        end

        % Button pushed function: SetBrainSpacePathButton
        function SetBrainSpacePathButtonPushed(app, event)
                    % 如果已经有了，就不重复
            if exist('GradientMaps','file')
                uialert(app.UIFigure, 'BrainSpace 已配置，无需重复设置', 'Info');
                return;
            end
        
            % 选择文件夹
            folder = uigetdir('', '请选择 BrainSpace 文件夹');
        
            if folder == 0
                return;
            end
        
            % 加入路径
            addpath(genpath(folder));
        
            % 保存路径
            try
                savepath;
            catch
                warning('路径未永久保存');
            end
        
            % 检查是否成功
            if exist('GradientMaps','file')
                uialert(app.UIFigure, 'BrainSpace 配置成功！', 'Success');
            else
                uialert(app.UIFigure, '未检测到 BrainSpace，请确认路径正确', 'Error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {518, 518};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {332, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: SparsitythresholdDropDown
        function SparsityDropDown(app, event)
       
            value = app.SparsitythresholdDropDown.Value;
        
            if strcmp(value,'Custom')
        
                app.SparsitythresholdEditField.Visible = 'on';
                app.SparsitythresholdEditField.Enable  = 'on';
        
            else
        
                app.SparsitythresholdEditField.Visible = 'off';
                app.SparsitythresholdEditField.Enable  = 'off';
        
                % 不要给 EditField 赋值
                % 不要读取 EditField 内容
        
            end
        
        end

        % Value changed function: NumberofgradientsDropDown
        function NumberofgradientsDropDownValueChanged(app, event)
       
        value = app.NumberofgradientsDropDown.Value;
        
        if strcmpi(value,'Custom')
        
            app.NumberofGradientsEditField.Visible = 'on';
            app.NumberofGradientsEditField.Enable  = 'on';
        
        else
        
            app.NumberofGradientsEditField.Visible = 'off';
            app.NumberofGradientsEditField.Enable  = 'off';
        
        end
        
        end

        % Value changed function: GradientAlignmentDropDown
        function GradientAlignmentDropDownValueChanged(app, event)
     
            uiValue = app.GradientAlignmentDropDown.Value;
        
            if isempty(app.UIFigure.UserData) || ~isstruct(app.UIFigure.UserData)
                app.UIFigure.UserData = struct;
            end
        
            switch uiValue
                case 'None'
                    app.UIFigure.UserData.AlignmentType = 'none';
        
                    app.nIterationsEditField.Visible = 'on';
                    app.ReferenceDropDown.Visible = 'on';
                    app.ReferenceDropDown.Enable = 'off';
                    app.nIterationsEditField.Enable = 'off';
        
                case 'Procrustes alignment'
                    app.UIFigure.UserData.AlignmentType = 'procrustes alignment';
        
                    app.nIterationsEditField.Visible = 'on';
                    app.ReferenceDropDown.Visible = 'on';
                    
                    app.ReferenceDropDown.Enable = 'on';
                    app.nIterationsEditField.Enable = 'on';
        
                    app.ReferenceDropDown.Items = {'the first gradient','mean'};
                    app.ReferenceDropDown.Value = 'mean';
        
                case 'Joint alignment'
                    app.UIFigure.UserData.AlignmentType = 'joint alignment';
        
                    app.nIterationsEditField.Visible = 'on';
                    app.ReferenceDropDown.Visible = 'on';
                    app.ReferenceDropDown.Enable = 'off';
                    app.nIterationsEditField.Enable = 'off';
        
            end
        
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
   
            Value = app.ListBox.Value;
        
            if isempty(Value)
                return;
            end
        
            UD = app.UIFigure.UserData;
        
            if ~isfield(UD,'InputFiles') || isempty(UD.InputFiles)
                return;
            end
        
            Items = UD.InputFiles;
        
            if ischar(Value) || isstring(Value)
        
                idx = find(strcmp(Items, Value));
        
            else
        
                idx = find(ismember(Items, Value));
        
            end
        
            if isempty(idx)
                return;
            end
        
            % =========================
            % delete files
            % =========================
            UD.InputFiles(idx) = [];
        
            % =========================
            % delete data
            % =========================
            if isfield(UD,'InputData')
        
                UD.InputData(idx) = [];
        
            end
        
            % =========================
            % delete all
            % =========================
            if isempty(UD.InputFiles)
        
                UD.InputType = [];
        
            end
        
            % =========================
            % save
            % =========================
            app.UIFigure.UserData = UD;
        
            % =========================
            % renew ListBox
            % =========================
            app.ListBox.Items = UD.InputFiles;
        
            if ~isempty(UD.InputFiles)
        
                app.ListBox.Value = UD.InputFiles{1};
        
            else
        
                app.ListBox.Items = {};
                app.ListBox.Value = {};
        
            end
        
            fprintf('Remaining subjects: %d\n', ...
                numel(UD.InputFiles));
        
        end

        % Value changed function: ListBox
        function ListBoxValueChanged2(app, event)
            value = app.ListBox.Value;
            
        end

        % Value changed function: KernelDropDown
        function KernelDropDownValueChanged(app, event)
            value = app.KernelDropDown.Value;
            
        end

        % Button pushed function: RemoveAllButton
        function RemoveAllButtonPushed(app, event)
                    
        % =========================
        % clean data
        % =========================
        app.UIFigure.UserData.FileList = {};
        
        
        if isfield(app.UIFigure.UserData, 'Data')
            app.UIFigure.UserData.Data = [];
        end
        
        if isfield(app.UIFigure.UserData, 'Header')
            app.UIFigure.UserData.Header = [];
        end
        
        if isfield(app.UIFigure.UserData, 'VoxelSize')
            app.UIFigure.UserData.VoxelSize = [];
        end
        
        % =========================
        % clean UI
        % =========================
        app.ListBox.Items = {};
        app.ListBox.Value = {};
        
        end

        % Button pushed function: GoSurfaceSpacegiiButton
        function GoSurfaceSpacegiiButtonPushed(app, event)

            % =====================================
            % Open SurfaceSpace
            % =====================================
            app2 = SurfaceSpace;
        
            % pass main app
            app2.MainApp = app;
        
            % =====================================
            % Restore Left Hemisphere
            % =====================================
            if isfield(app.UIFigure.UserData, ...
                    'LH_GII')
        
                app2.UIFigure.UserData.LH_GII = ...
                    app.UIFigure.UserData.LH_GII;
        
                app2.ListBox.Items = ...
                    app.UIFigure.UserData.LH_GII;
        
            end
        
            % =====================================
            % Restore Right Hemisphere
            % =====================================
            if isfield(app.UIFigure.UserData, ...
                    'RH_GII')
        
                app2.UIFigure.UserData.RH_GII = ...
                    app.UIFigure.UserData.RH_GII;
        
                app2.ListBox_2.Items = ...
                    app.UIFigure.UserData.RH_GII;
        
            end
       
        end

        % Button pushed function: CalGradientButton
        function CalGradientButtonPushed(app, event)
        
        clear functions
        rehash
        
        try
            % =========================================
            % Check input
            % =========================================
            UD = app.UIFigure.UserData;
            if isempty(UD) || ~isstruct(UD)
                error('No input data loaded.');
            end
        
            if isempty(UD) || ~isfield(UD,'InputData')
                error('No input data loaded.');
            end
        
            InputData = UD.InputData;
        
            InputFiles = [];
            if isfield(UD,'InputFiles')
                InputFiles = UD.InputFiles;
            end
        
            InputType = UD.InputType;
        
            % =========================================
            % Output directory
            % =========================================
            if ~isfield(UD,'OutputDir')
                error('Output directory not set.');
            end
            OutDir = UD.OutputDir;
        
            % =========================================
            % Alignment
            % =========================================
            AlignmentType = lower(strtrim(app.GradientAlignmentDropDown.Value));
            
            % =========================================
            % Reference
            % =========================================
            refStrategy = lower(strtrim(app.ReferenceDropDown.Value));
            
            % =========================================
            % nIterations
            % =========================================
            nIterations = double(app.nIterationsEditField.Value);
            if isnan(nIterations) || nIterations <= 0
                nIterations = 10;
            end
            % =========================================
            % Sparsity
            % =========================================
            val = app.SparsitythresholdDropDown.Value;
            
            if strcmp(val,'Custom')
                raw = app.SparsitythresholdEditField.Value;
            else
                raw = str2double(regexprep(string(val), '[^\d\.]', ''));
            end
            
            SparsityThreshold = 100 - raw;
            
            if isempty(SparsityThreshold) || isnan(SparsityThreshold)
                error('Please enter a valid sparsity threshold value.');
            end
            fprintf('Sparsity mode = %s\n', val);
            fprintf('Sparsity value = %.2f\n', SparsityThreshold);
            % =========================================
            % Number of Gradients 
            % =========================================
            gradVal = app.NumberofgradientsDropDown.Value;
            
            if strcmpi(gradVal,'Custom')
            
                nComp = app.NumberofGradientsEditField.Value;
                nComp = str2double(nComp);
            
            else
            
                nComp = str2double(gradVal);
            
            end
            
            % safety
            if isempty(nComp) || isnan(nComp)
                nComp = 10;
            end
            
            nComp = round(nComp);
            nComp = max(2, min(50, nComp));
            
            fprintf('nComp value = %d\n', nComp);
            
            % =========================================
            % DEBUG
            % =========================================
            fprintf('\n[DEBUG] InputType = %s\n', InputType);
            fprintf('[DEBUG] AlignmentType = %s\n', AlignmentType);
            fprintf('[DEBUG] refStrategy = %s\n', refStrategy);
            fprintf('[DEBUG] nIterations = %d\n', nIterations);
            fprintf('[DEBUG] SparsityThreshold = %.2f\n', SparsityThreshold);
            fprintf('\n[DEBUG] nComp before backend = %d\n', nComp);
        
            % =========================================
            % Call backend
            % =========================================
            Result = Gradient_Calculation( ...
                InputData, ...
                InputFiles, ...
                InputType, ...
                OutDir, ...
                AlignmentType, ...
                refStrategy, ...
                nIterations, ...
                SparsityThreshold, ...
                nComp);
        
            % =========================================
            % Save result
            % =========================================

            app.UIFigure.UserData.GradientResult = Result;

            uialert(app.UIFigure, ...
                'Gradient calculation finished successfully.', ...
                'Success', ...
                'Icon','success');

            fprintf('\nGradient calculation completed.\n');

        catch ME

            uialert(app.UIFigure, ...
                ME.message, ...
                'Calculation Error', ...
                'Icon','error');

            fprintf('\nERROR:\n%s\n', ME.message);

            disp(getReport(ME,'extended'));
        end
        
        end

        % Button pushed function: CalGradientMetricsButton
        function CalGradientMetricsButtonPushed(app, event)
        
        try
            % =========================
            % Check if any gradient data has been added
            % =========================
            if ~isfield(app.UIFigure.UserData,'MetricsGradient') || isempty(app.UIFigure.UserData.MetricsGradient)
                uialert(app.UIFigure, ...
                    'Please add gradient data first using "Add Gradient" button.', ...
                    'Missing Input','Icon','error');
                return;
            end
        
            % =========================
            % Select which gradient to calculate metrics for
            % =========================
            MetricsGradient = app.UIFigure.UserData.MetricsGradient;
            if isempty(MetricsGradient)
                uialert(app.UIFigure, 'No gradient data available.','Error','Icon','error');
                return;
            end
        
            % default the last gradient
            GradientResult = MetricsGradient{end};
        
            % =========================
            % Check output folder
            % =========================
            if ~isfield(app.UIFigure.UserData,'OutputDir') || isempty(app.UIFigure.UserData.OutputDir)
                uialert(app.UIFigure,'Please set output folder first using "OutputDir" button.','Missing Output','Icon','error');
                return;
            end
            OutDir = app.UIFigure.UserData.OutputDir;
        
            % =========================
            % Set calculation options
            % =========================
            Options.ExplanationRatio   = true;
            Options.GradientRange      = true;
            Options.GradientVariance   = true;
            Options.GradientDispersion = true;
        
            % =========================
            % Call Metrics Calculation
            % =========================
            Metrics = Gradient_Metrics_Calculation(GradientResult, Options, OutDir);
        
            % =========================
            % Save Metrics info in GUI memory
            % =========================
            app.UIFigure.UserData.GradientMetrics     = Metrics;
            app.UIFigure.UserData.GradientMetricsPath = OutDir;
        
            % =========================
            % Success message
            % =========================
            uialert(app.UIFigure, ...
                sprintf('Gradient metrics calculated and saved for each subject in:\n%s', OutDir), ...
                'Success','Icon','success');
        
        catch ME
            uialert(app.UIFigure, ME.message,'Metrics Error','Icon','error');
        end
        
        end

        % Value changed function: ExplanationratioCheckBox
        function ExplanationratioCheckBoxValueChanged(app, event)
            value = app.ExplanationratioCheckBox.Value;
        
            % initial
            if ~isfield(app.UIFigure.UserData,'Options')
                app.UIFigure.UserData.Options = struct;
            end
        
            % save state
            app.UIFigure.UserData.Options.ExplanationRatio = ...
                app.ExplanationratioCheckBox.Value;

        end

        % Value changed function: GradientrangeCheckBox
        function GradientrangeCheckBoxValueChanged(app, event)
            value = app.GradientrangeCheckBox.Value;

            if ~isfield(app.UIFigure.UserData,'Options')
                app.UIFigure.UserData.Options = struct;
            end
        
            app.UIFigure.UserData.Options.GradientRange = ...
                app.GradientrangeCheckBox.Value;

        end

        % Value changed function: GradientvarianceCheckBox
        function GradientvarianceCheckBoxValueChanged(app, event)
            value = app.GradientvarianceCheckBox.Value;
            
            if ~isfield(app.UIFigure.UserData,'Options')
            app.UIFigure.UserData.Options = struct;
        end
    
        app.UIFigure.UserData.Options.GradientVariance = ...
            app.GradientvarianceCheckBox.Value;
        
        end

        % Value changed function: GradientdispersionCheckBox
        function GradientdispersionCheckBoxValueChanged(app, event)
            value = app.GradientdispersionCheckBox.Value;

            if ~isfield(app.UIFigure.UserData,'Options')
                app.UIFigure.UserData.Options = struct;
            end
        
            app.UIFigure.UserData.Options.GradientDispersion = ...
                app.GradientdispersionCheckBox.Value;
        
        end

        % Button pushed function: OutputDirButton
        function OutputDirButtonPushed(app, event)

            % =====================================
            % Select Output Folder
            % =====================================
            folder = uigetdir(pwd, ...
                'Select Output Folder');
        
            % cancel
            if isequal(folder,0)
                return;
            end
        
            % =====================================
            % Display
            % =====================================
            app.OutputDirEditField.Value = folder;
        
            % =====================================
            % Store Safely
            % =====================================
            if isempty(app.UIFigure.UserData)
        
                app.UIFigure.UserData = struct;
        
            end
        
            app.UIFigure.UserData.OutputDir = folder;
        
            fprintf('\nOutput Folder:\n%s\n', ...
                folder);

        end

        % Button pushed function: AddGradientButton
        function AddGradientButtonPushed(app, event)
        
        try
            % =========================
            % Select gradient .mat file
            % =========================
            [file,path] = uigetfile('*.mat','Select Gradient Result File');
            if isequal(file,0)
                return; % user canceled
            end
            fullpath = fullfile(path,file);
        
            % =========================
            % Load Result struct
            % =========================
            S = load(fullpath);
            if ~isfield(S,'Result')
                uialert(app.UIFigure, 'Selected .mat does not contain Result struct.','Error','Icon','error');
                return;
            end
            ResultStruct = S.Result;
        
            % =========================
            % Initialize or append MetricsGradient
            % =========================
            if ~isfield(app.UIFigure.UserData,'MetricsGradient') || isempty(app.UIFigure.UserData.MetricsGradient)
                MetricsGradient = {};
            else
                MetricsGradient = app.UIFigure.UserData.MetricsGradient;
            end
            MetricsGradient{end+1} = ResultStruct;
            app.UIFigure.UserData.MetricsGradient = MetricsGradient;
        
            % =========================
            % Update ListBox
            % =========================
            items = cellfun(@(x) sprintf('Gradient_%d',find(cellfun(@(y) isequal(y,x),MetricsGradient))), ...
                            MetricsGradient,'UniformOutput',false);
            app.ListBox_2.Items = items;
            app.ListBox_2.Value = items{end};
        
            % =========================
            % Success message
            % =========================
            uialert(app.UIFigure,'Gradient successfully added.','Success','Icon','success');
        
        catch ME
            uialert(app.UIFigure, ME.message,'Error','Icon','error');
        end
        
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
     
        try
        
            % =========================================
            % Check list
            % =========================================
            if isempty(app.ListBox_2.Items)
        
                uialert(app.UIFigure, ...
                    'No gradient to remove.', ...
                    'Empty List', ...
                    'Icon','warning');
                return;
        
            end
        
            % =========================================
            % Get selected item
            % =========================================
            selected = app.ListBox_2.Value;
        
            if isempty(selected)
        
                uialert(app.UIFigure, ...
                    'Please select a gradient first.', ...
                    'No Selection', ...
                    'Icon','warning');
                return;
        
            end
        
            items = app.ListBox_2.Items;
        
            % =========================================
            % Find index
            % =========================================
            idx = find(strcmp(items, selected), 1);
        
            if isempty(idx)
        
                uialert(app.UIFigure, ...
                    'Selected item not found.', ...
                    'Error', ...
                    'Icon','error');
                return;
        
            end
        
            % =========================================
            % Remove from ListBox
            % =========================================
            items(idx) = [];
            app.ListBox_2.Items = items;
        
            % =========================================
            % Sync UserData (MetricsGradientFiles)
            % =========================================
            if isfield(app.UIFigure.UserData,'MetricsGradientFiles')
        
                fileList = app.UIFigure.UserData.MetricsGradientFiles;
        
                if idx <= numel(fileList)
                    fileList(idx) = [];
                end
        
                app.UIFigure.UserData.MetricsGradientFiles = fileList;
        
            end
        
            % =========================================
            % Sync MetricsGradient (IMPORTANT)
            % =========================================
            if isfield(app.UIFigure.UserData,'MetricsGradient')
        
                data = app.UIFigure.UserData.MetricsGradient;
        
                if idx <= numel(data)
                    data(idx) = [];
                end
        
                app.UIFigure.UserData.MetricsGradient = data;
        
            end
        
            % =========================================
            % Reset selection safely
            % =========================================
            if ~isempty(items)
                app.ListBox_2.Value = items{1};
            else
                app.ListBox_2.Value = {};
            end
        
            % =========================================
            % Reset index (optional but safe)
            % =========================================
            app.UIFigure.UserData.CurrentGradientIndex = 1;
        
        catch ME
        
            uialert(app.UIFigure, ...
                ME.message, ...
                'Remove Error', ...
                'Icon','error');
        
        end
        
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
       
        value = app.ListBox_2.Value;
        
        % =========================
        % initial
        % =========================
        if ~isfield(app.UIFigure.UserData,'VisualizationOptions')
            app.UIFigure.UserData.VisualizationOptions = struct;
        end
        
        % =========================
        % only save the gradient now
        % =========================
        app.UIFigure.UserData.CurrentGradient = value;
        
        end

        % Value changed function: GradientmapsCheckBox
        function GradientmapsCheckBoxValueChanged(app, event)
            value = app.GradientmapsCheckBox.Value;
       
            % initial
            if ~isfield(app.UIFigure.UserData,'VisualizationOptions')
                app.UIFigure.UserData.VisualizationOptions = struct;
            end
        
            % save states
            app.UIFigure.UserData.VisualizationOptions.GradientMaps = ...
                app.GradientmapsCheckBox.Value;
        
     
        end

        % Value changed function: ScatterplotCheckBox
        function ScatterplotCheckBoxValueChanged(app, event)
            value = app.ScatterplotCheckBox.Value;
            % initial
            if ~isfield(app.UIFigure.UserData,'VisualizationOptions')
                app.UIFigure.UserData.VisualizationOptions = struct;
            end
        
            % save states
            app.UIFigure.UserData.VisualizationOptions.ScatterPlot = ...
                app.ScatterplotCheckBox.Value;
        end

        % Value changed function: ScreeplotCheckBox
        function ScreeplotCheckBoxValueChanged(app, event)
            value = app.ScreeplotCheckBox.Value;
             
            if ~isfield(app.UIFigure.UserData,'VisualizationOptions')
                app.UIFigure.UserData.VisualizationOptions = struct;
            end
        
            % save states
            app.UIFigure.UserData.VisualizationOptions.ScreePlot = ...
                app.ScreeplotCheckBox.Value;
        end

        % Value changed function: CalGradientVisualizationButton
        function CalGradientVisualizationButtonValueChanged(app, event)
        
        try
            % =========================
            % Check input
            % =========================
            if ~isfield(app.UIFigure.UserData,'MetricsGradient') || ...
                    isempty(app.UIFigure.UserData.MetricsGradient)
        
                uialert(app.UIFigure, ...
                    'Please add gradient result first.', ...
                    'Missing Input','Icon','error');
                return;
            end
        
            GradientResult = app.UIFigure.UserData.MetricsGradient{end};
        
            % =========================
            % Check options
            % =========================
            if ~isfield(app.UIFigure.UserData,'VisualizationOptions')
                opts = struct;
            else
                opts = app.UIFigure.UserData.VisualizationOptions;
            end
        
            if ~isfield(opts,'ScatterPlot'), opts.ScatterPlot = false; end
            if ~isfield(opts,'ScreePlot'), opts.ScreePlot = false; end
            if ~isfield(opts,'GradientMaps'), opts.GradientMaps = false; end
        
            % =========================
            % Output dir
            % =========================
            if ~isfield(app.UIFigure.UserData,'OutputDir') || ...
                    isempty(app.UIFigure.UserData.OutputDir)
        
                uialert(app.UIFigure, ...
                    'Please select output directory first.', ...
                    'Missing OutputDir','Icon','error');
                return;
            end
        
            OutDir = app.UIFigure.UserData.OutputDir;
        
            % =========================
            % SAFE TYPE FIX
            % =========================
            if iscell(OutDir)
                OutDir = OutDir{1};
            end
            OutDir = char(string(OutDir));
        
            % =========================
            % CALL FUNCTION (FIXED ORDER)
            % =========================
            Gradient_Visualization(GradientResult, OutDir, opts);
        
            uialert(app.UIFigure, ...
                'Gradient visualization finished successfully.', ...
                'Success','Icon','success');
        
        catch ME
            uialert(app.UIFigure, ME.message, 'Visualization Error','Icon','error');
            fprintf('\nERROR:\n%s\n', ME.message);
        end
        
        end

        % Value changed function: SparsitythresholdEditField
        function SparsitythresholdEditFieldValueChanged(app, event)
        
            value = app.SparsitythresholdEditField.Value;
        
            % allow empty（no custom）
            if isempty(value)
                return;
            end
        
        end

        % Value changed function: ReferenceDropDown
        function ReferenceDropDownValueChanged(app, event)

            value = app.ReferenceDropDown.Value;
        
            if isempty(app.UIFigure.UserData) || ~isstruct(app.UIFigure.UserData)
                app.UIFigure.UserData = struct();
            end
        
            app.UIFigure.UserData.ReferenceUI = value;
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
        
        % =========================
        % select mask file
        % =========================
        [file, path] = uigetfile( ...
            {'*.mat;*.txt;*.csv;*.gii;*.nii;*.nii.gz', ...
            'Mask Files'}, ...
            'Select Mask File');
        
        if isequal(file,0)
            return;
        end
        
        maskFile = fullfile(path, file);
        
        % =========================
        % load mask
        % =========================
        [~,~,ext] = fileparts(maskFile);
        
        switch lower(ext)
        
            case '.mat'
                tmp = load(maskFile);
                fn = fieldnames(tmp);
                mask = tmp.(fn{1});
        
            case '.txt'
                mask = readmatrix(maskFile);
        
            case '.csv'
                mask = readmatrix(maskFile);
        
            case '.gii'
                g = gifti(maskFile);
                mask = g.cdata;
        
            case {'.nii','.gz'}
                mask = niftiread(maskFile);
        
            otherwise
                uialert(app.UIFigure,'Unsupported mask format','Error');
                return;
        end
        
        % =========================
        % normalize mask
        % =========================
        mask = mask(:);
        
        if ~islogical(mask)
            mask = mask > 0;
        end
        
        % =========================
        % store mask
        % =========================
        if isempty(app.UIFigure.UserData) || ~isstruct(app.UIFigure.UserData)
            app.UIFigure.UserData = struct();
        end
        
        app.UIFigure.UserData.Mask = mask;
        
        % =========================
        % feedback
        % =========================
        uialert(app.UIFigure, ...
            sprintf('Mask loaded: %d vertices', sum(mask)), ...
            'Success','Icon','success');
        
        fprintf('Mask loaded: %d vertices\n', sum(mask));
                   
        end

        % Value changed function: NumberofGradientsEditField
        function NumberofGradientsEditFieldValueChanged(app, event)
        value = app.NumberofGradientsEditField.Value;
        
        value = str2double(value);
        
        if isempty(value) || isnan(value)
            value = 10;
        end
        
        value = round(value);
        value = max(2, min(50, value));
        
        app.NumberofGradientsEditField.Value = string(value);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.AddimageButton = uibutton(app.GridLayout3, 'push');
            app.AddimageButton.ButtonPushedFcn = createCallbackFcn(app, @AddimageButtonPushed, true);
            app.AddimageButton.Text = 'Add image';
            app.RemoveButton = uibutton(app.GridLayout3, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.MaskButton = uibutton(app.GridLayout3, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.GradientCalculationLabel = uilabel(app.GridLayout3);
            app.GradientCalculationLabel.Text = 'Gradient Calculation';
            app.DownloadBrainspaceButton = uibutton(app.GridLayout3, 'push');
            app.DownloadBrainspaceButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadBrainspaceButtonPushed2, true);
            app.DownloadBrainspaceButton.Text = 'Download Brainspace';
            app.SetBrainSpacePathButton = uibutton(app.GridLayout3, 'push');
            app.SetBrainSpacePathButton.ButtonPushedFcn = createCallbackFcn(app, @SetBrainSpacePathButtonPushed, true);
            app.SetBrainSpacePathButton.Text = 'Set BrainSpace Path';
            app.CalGradientButton = uibutton(app.GridLayout3, 'push');
            app.CalGradientButton.ButtonPushedFcn = createCallbackFcn(app, @CalGradientButtonPushed, true);
            app.CalGradientButton.Text = 'Cal Gradient';
            app.ReferenceLabel_2 = uilabel(app.GridLayout3);
            app.ReferenceLabel_2.Visible = 'off';
            app.ReferenceLabel_2.Text = 'Reference';
            app.nIterationsLabel_2 = uilabel(app.GridLayout3);
            app.nIterationsLabel_2.Visible = 'off';
            app.nIterationsLabel_2.Text = 'nIterations';
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged2, true);
            app.ListBox.Value = {};
            app.KernelDropDownLabel = uilabel(app.GridLayout3);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.GridLayout3);
            app.KernelDropDown.Items = {'normalized angle', 'none', 'pearson', 'spearman', 'gaussian', 'cosine similarity'};
            app.KernelDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelDropDownValueChanged, true);
            app.KernelDropDown.Value = 'normalized angle';
            app.ApproachDropDownLabel = uilabel(app.GridLayout3);
            app.ApproachDropDownLabel.Text = 'Approach';
            app.ApproachDropDown = uidropdown(app.GridLayout3);
            app.ApproachDropDown.Items = {'diffusion embedding', 'principal component analysis', 'laplacian eigenmap'};
            app.ApproachDropDown.Value = 'diffusion embedding';
            app.NumberofgradientsDropDownLabel = uilabel(app.GridLayout3);
            app.NumberofgradientsDropDownLabel.Text = {'Number of gradients'; ''};
            app.NumberofgradientsDropDown = uidropdown(app.GridLayout3);
            app.NumberofgradientsDropDown.Items = {'10', 'Custom'};
            app.NumberofgradientsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofgradientsDropDownValueChanged, true);
            app.NumberofgradientsDropDown.Value = '10';
            app.NumberofGradientsEditFieldLabel = uilabel(app.GridLayout3);
            app.NumberofGradientsEditFieldLabel.Enable = 'off';
            app.NumberofGradientsEditFieldLabel.Visible = 'off';
            app.NumberofGradientsEditFieldLabel.Text = 'NumberofGradientsEditField';
            app.NumberofGradientsEditField = uieditfield(app.GridLayout3, 'text');
            app.NumberofGradientsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofGradientsEditFieldValueChanged, true);
            app.NumberofGradientsEditField.Enable = 'off';
            app.NumberofGradientsEditField.Visible = 'off';
            app.nIterationsLabel = uilabel(app.GridLayout3);
            app.nIterationsLabel.Text = 'nIterations';
            app.nIterationsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.nIterationsEditField.Enable = 'off';
            app.nIterationsEditField.Value = 10;
            app.GoSurfaceSpacegiiButton = uibutton(app.GridLayout3, 'push');
            app.GoSurfaceSpacegiiButton.ButtonPushedFcn = createCallbackFcn(app, @GoSurfaceSpacegiiButtonPushed, true);
            app.GoSurfaceSpacegiiButton.Text = 'Go Surface Space (.gii)';
            app.RemoveAllButton = uibutton(app.GridLayout3, 'push');
            app.RemoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButtonPushed, true);
            app.RemoveAllButton.Text = 'Remove All';
            app.ReferenceLabel = uilabel(app.GridLayout3);
            app.ReferenceLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.GridLayout3);
            app.ReferenceDropDown.Items = {'the first gradient', 'mean'};
            app.ReferenceDropDown.Editable = 'on';
            app.ReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceDropDownValueChanged, true);
            app.ReferenceDropDown.Enable = 'off';
            app.ReferenceDropDown.Value = 'the first gradient';
            app.GradientAlignmentDropDownLabel = uilabel(app.GridLayout3);
            app.GradientAlignmentDropDownLabel.Text = 'Gradient Alignment';
            app.GradientAlignmentDropDown = uidropdown(app.GridLayout3);
            app.GradientAlignmentDropDown.Items = {'None', 'Procrustes alignment', 'Joint alignment'};
            app.GradientAlignmentDropDown.Editable = 'on';
            app.GradientAlignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @GradientAlignmentDropDownValueChanged, true);
            app.GradientAlignmentDropDown.Value = 'None';
            app.SparsitythresholdEditFieldLabel = uilabel(app.GridLayout3);
            app.SparsitythresholdEditFieldLabel.Visible = 'off';
            app.SparsitythresholdEditFieldLabel.Text = {'Sparsity threshold'; ''};
            app.SparsitythresholdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SparsitythresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SparsitythresholdEditFieldValueChanged, true);
            app.SparsitythresholdEditField.Visible = 'off';
            app.SparsitythresholdDropDown = uidropdown(app.GridLayout3);
            app.SparsitythresholdDropDown.Items = {'''10''', 'Custom'};
            app.SparsitythresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @SparsityDropDown, true);
            app.SparsitythresholdDropDown.Value = '''10''';
            app.SparsitythresholdDropDownLabel = uilabel(app.GridLayout3);
            app.SparsitythresholdDropDownLabel.Text = {'Sparsity threshold'; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GradientMetricsLabel = uilabel(app.GridLayout2);
            app.GradientMetricsLabel.Text = {'Gradient Metrics'; ''};
            app.ExplanationratioCheckBox = uicheckbox(app.GridLayout2);
            app.ExplanationratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExplanationratioCheckBoxValueChanged, true);
            app.ExplanationratioCheckBox.Text = 'Explanation ratio';
            app.GradientrangeCheckBox = uicheckbox(app.GridLayout2);
            app.GradientrangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @GradientrangeCheckBoxValueChanged, true);
            app.GradientrangeCheckBox.Text = {'Gradient range'; ''};
            app.GradientvarianceCheckBox = uicheckbox(app.GridLayout2);
            app.GradientvarianceCheckBox.ValueChangedFcn = createCallbackFcn(app, @GradientvarianceCheckBoxValueChanged, true);
            app.GradientvarianceCheckBox.Text = {'Gradient variance'; ''};
            app.GradientdispersionCheckBox = uicheckbox(app.GridLayout2);
            app.GradientdispersionCheckBox.ValueChangedFcn = createCallbackFcn(app, @GradientdispersionCheckBoxValueChanged, true);
            app.GradientdispersionCheckBox.Text = {'Gradient dispersion'; ''};
            app.GradientVisualizationLabel = uilabel(app.GridLayout2);
            app.GradientVisualizationLabel.Text = {'Gradient Visualization'; ''};
            app.GradientmapsCheckBox = uicheckbox(app.GridLayout2);
            app.GradientmapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GradientmapsCheckBoxValueChanged, true);
            app.GradientmapsCheckBox.Text = {'Gradient maps'; ''};
            app.ScatterplotCheckBox = uicheckbox(app.GridLayout2);
            app.ScatterplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScatterplotCheckBoxValueChanged, true);
            app.ScatterplotCheckBox.Text = {'Scatter plot'; ''};
            app.ScreeplotCheckBox = uicheckbox(app.GridLayout2);
            app.ScreeplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScreeplotCheckBoxValueChanged, true);
            app.ScreeplotCheckBox.Text = {'Scree plot'; ''};
            app.OutputDirButton = uibutton(app.GridLayout2, 'push');
            app.OutputDirButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDirButtonPushed, true);
            app.OutputDirButton.Text = '…';
            app.OutputDirEditFieldLabel = uilabel(app.GridLayout2);
            app.OutputDirEditFieldLabel.Text = 'Output Dir';
            app.OutputDirEditField = uieditfield(app.GridLayout2, 'text');
            app.CalGradientMetricsButton = uibutton(app.GridLayout2, 'push');
            app.CalGradientMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @CalGradientMetricsButtonPushed, true);
            app.CalGradientMetricsButton.Text = 'Cal Gradient Metrics';
            app.RemoveButton_2 = uibutton(app.GridLayout2, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.AddGradientButton = uibutton(app.GridLayout2, 'push');
            app.AddGradientButton.ButtonPushedFcn = createCallbackFcn(app, @AddGradientButtonPushed, true);
            app.AddGradientButton.Text = 'Add Gradient';
            app.GradientMetricsCalculationLabel = uilabel(app.GridLayout2);
            app.GradientMetricsCalculationLabel.Text = 'Gradient Metrics Calculation';
            app.CalGradientVisualizationButton = uibutton(app.GridLayout2, 'state');
            app.CalGradientVisualizationButton.ValueChangedFcn = createCallbackFcn(app, @CalGradientVisualizationButtonValueChanged, true);
            app.CalGradientVisualizationButton.Text = 'Cal Gradient Visualization';
            app.ListBox_2 = uilistbox(app.GridLayout2);
            app.ListBox_2.Items = {};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dpabi_Gradient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end